classdef OrbitGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GroundtrackCheckBox            matlab.ui.control.CheckBox
        DateDatePicker                 matlab.ui.control.DatePicker
        DateDatePickerLabel            matlab.ui.control.Label
        FinalArgofperigeedegEditField  matlab.ui.control.NumericEditField
        FinalArgofperigeedegEditFieldLabel  matlab.ui.control.Label
        FinalRAANdegEditField          matlab.ui.control.NumericEditField
        FinalRAANdegEditFieldLabel     matlab.ui.control.Label
        WhenJ2isactiveLabel            matlab.ui.control.Label
        finaltrueanomalydegEditField   matlab.ui.control.NumericEditField
        finaltrueanomalydegEditFieldLabel  matlab.ui.control.Label
        MinSpinner                     matlab.ui.control.Spinner
        MinSpinnerLabel                matlab.ui.control.Label
        HrsSpinner                     matlab.ui.control.Spinner
        HrsSpinnerLabel                matlab.ui.control.Label
        UserdefinedtimeUTCCheckBox     matlab.ui.control.CheckBox
        minutesLabel                   matlab.ui.control.Label
        hoursLabel                     matlab.ui.control.Label
        andSpinner                     matlab.ui.control.Spinner
        andSpinnerLabel                matlab.ui.control.Label
        LONEditField                   matlab.ui.control.NumericEditField
        LONEditFieldLabel              matlab.ui.control.Label
        LATEditField                   matlab.ui.control.NumericEditField
        LATEditFieldLabel              matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        kmsLabel                       matlab.ui.control.Label
        KEditField                     matlab.ui.control.NumericEditField
        KEditFieldLabel                matlab.ui.control.Label
        JEditField                     matlab.ui.control.NumericEditField
        JEditFieldLabel                matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        IEditFieldLabel                matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        LastpositionvectorsLabel       matlab.ui.control.Label
        MinspeedkmEditField            matlab.ui.control.NumericEditField
        MinspeedkmEditFieldLabel       matlab.ui.control.Label
        MaxspeedkmEditField            matlab.ui.control.NumericEditField
        MaxspeedkmEditFieldLabel       matlab.ui.control.Label
        SpeedAltitudeCheckBox          matlab.ui.control.CheckBox
        PeriodhrsEditField             matlab.ui.control.NumericEditField
        PeriodhrsEditFieldLabel        matlab.ui.control.Label
        AltitudekmEditField            matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel       matlab.ui.control.Label
        NoofrevolutionsEditField       matlab.ui.control.NumericEditField
        NoofrevolutionsEditFieldLabel  matlab.ui.control.Label
        ApogeekmEditField              matlab.ui.control.NumericEditField
        ApogeekmEditFieldLabel         matlab.ui.control.Label
        PerigeekmEditField             matlab.ui.control.NumericEditField
        PerigeekmEditFieldLabel        matlab.ui.control.Label
        SemimajorAxiskmEditField       matlab.ui.control.NumericEditField
        SemimajorAxiskmEditFieldLabel  matlab.ui.control.Label
        J2PerturbationSwitch           matlab.ui.control.Switch
        J2PerturbationSwitchLabel      matlab.ui.control.Label
        MeanAnomalydegSpinner          matlab.ui.control.Spinner
        MeanAnomalydegSpinnerLabel     matlab.ui.control.Label
        ArgofPerigeedegSpinner         matlab.ui.control.Spinner
        ArgofPerigeedegSpinnerLabel    matlab.ui.control.Label
        RAANdegSpinner                 matlab.ui.control.Spinner
        RAANdegSpinnerLabel            matlab.ui.control.Label
        InclinationdegSpinner          matlab.ui.control.Spinner
        InclinationdegSpinnerLabel     matlab.ui.control.Label
        MeanMotionSpinner              matlab.ui.control.Spinner
        MeanMotionSpinnerLabel         matlab.ui.control.Label
        EccentricitySpinner            matlab.ui.control.Spinner
        EccentricitySpinnerLabel       matlab.ui.control.Label
        DurationSpinner                matlab.ui.control.Spinner
        DurationSpinnerLabel           matlab.ui.control.Label
        GenerateorbitButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateorbitButton
        function GenerateorbitButtonPushed(app, event)
%Chen's_orbit_propagator
%this programme runs when the (Generate) bottun in pushed
%graph the altitude and speed over time
%this program for transforming from IJK to PQW (Q2)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
close all
clc

%define paramaeters and constants
%all distance units are in km, speed units in km/s, and angles in degrees,
%time in hours
Re  = 6378.137;
mue=398600.44189; %km^2/s
EarthRot = 86164.09053; %86164.09053
e=app.EccentricitySpinner.Value; %dimensionless
n=app.MeanMotionSpinner.Value; %rev/day
t=n*2*pi/86164; % rad/s %86400
a=(mue^(1/3))/(t^(2/3)); %km
i=app.InclinationdegSpinner.Value; %deg
Omega=app.RAANdegSpinner.Value; %deg
w=app.ArgofPerigeedegSpinner.Value; %deg
nue=app.MeanAnomalydegSpinner.Value; %deg
h=sqrt(a*(1-e^2)*mue); 
p=(h^2)/mue;

%calculating the true anomaly from mean anomaly
M = nue;
E = M;
FE = E-(e*sind(E))-M;
theta = MeanAnom(M,E,FE);
r=p/(1+(e*cosd(theta)));

%parameters to R and V vectors in orbital plane
rv =[r*cosd(theta) r*sind(theta) 0];
vv=sqrt(mue/p)*[-sind(theta) (e+cosd(theta)) 0];
%transformation from orbital frame to Earth inertial frame
DCMT=CordTrans(w,Omega,i);
%the position and velocity in Earth inertial frame
rvx=DCMT*rv';
vvx=DCMT*vv';
T = 2*pi*(a^(3/2))/sqrt(mue);

% initial conditions
r0 = rvx;
v0 = vvx;
y0 = [r0;v0];

%the timespan
N=app.DurationSpinner.Value;
Min=app.andSpinner.Value;
Period=N*3600 + Min*60;
tspan = [0 Period];
NofRev = Period/T;

%calling ode solver
Odefunc = @J2ode;
options = odeset('AbsTol', 1e-10, 'RelTol', 1e-10);
[t,y]= ode113(Odefunc,tspan,y0,options);
xv = y(:,1);
yv = y(:,2);
zv = y(:,3);

%plot the orbit 
lenx = length(xv); %will be used for Eearth rotation
figure('Name','3D-view window','NumberTitle','off')
 %mapping the Earth
Map = imread('earthmap.jpg');
%[X, Y, Z]= sphere
%Earth = surf(Re*X, Re*Y, -Re*Z)
rpol = 6357; %Eearth radius at the poles
[X, Y, Z]= ellipsoid(0,0,0,Re,Re,rpol);
Earth = surf(X, Y, -Z);
set(Earth,'FaceColor','texturemap','Cdata',Map,'EdgeColor','none')
set(gcf,'Color','k')
set (gca,'Color','k')
axis equal
if n <= 17
ax = gca;
ax.Clipping = 'off';
axis off
end
O = findobj('Type','surface');

revspeed = 360/EarthRot; %deg per sec
EarthPhase = Period*revspeed; %phase of earth rotation in deg

%data for last position
LastPosition=[y(end,1) y(end,2) y(end,3)];
RX=LastPosition(1);
RY=LastPosition(2);
RZ=LastPosition(3);

   %speed and atitude calculation
    l=length(t);
%speed vector
   for ii=1:1:l
    Speedv=y(ii,4:6);
    Rt(ii)=norm(y(ii,1:3))-Re; 
    Speed(ii)=norm(y(ii,4:6)); 
   end

rotM= 360/(EarthRot/60); %rotation of earth deg/min            %rotM= 360/(EarthRot/60);

    %Define time
    %transform time from UTC ot Julian 
    TimeUTC = datetime('now', 'Format','yyyy MM dd HH mm ss', 'TimeZone','local');   % Local Time Zone
    TimeUTC.TimeZone = 'z';
    Utc= datevec(TimeUTC);
  
%Check time selection argument
        loctH = Utc(4);
        loctM = Utc(5);
        loctHM = (loctH*60) + (loctM);
        JD = juliandate(Utc);
     
     Checkbox2 = app.UserdefinedtimeUTCCheckBox.Value;
     
     if Checkbox2 == 1
         date = app.DateDatePicker.Value;
        datev = datevec(date);
         Year = datev(1);
         Month = datev(2);
         Day = datev(3);
         Hour = app.HrsSpinner.Value;
         Minute = app.MinSpinner.Value;
         UtC = [Year Month Day Hour Minute 0.000];
      
         loctH = UtC(4);
        loctM = UtC(5);
        loctHM = (loctH*60) + (loctM);
        JD = juliandate(UtC);
     end

   %transform ECI to ECEF
     Thetagdeg = jd2gmst(JD,loctH,loctM);
     dcmCEFE=[cosd(Thetagdeg) sind(Thetagdeg) 0;-sind(Thetagdeg) cosd(Thetagdeg) 0;0 0 1];
     CEFE = dcmCEFE*LastPosition'; %position in ecef
     
    %rotate Eearth with accordance with ECEF frame
    rotate(O,[0 0 1],Thetagdeg,[0 0 0])
    hold on

    %Earth rotation 
tr=EarthPhase/(lenx);

%rotating the Earth according to time selected
for q = 1:1:loctHM
   rotate(O,[0 0 1],rotM,[0 0 0]);
end
    phi1 = rotM*loctHM;
   hold on

%draw the orbit
plot3(xv(1),yv(1),zv(1),'Marker',"o",'Color',[1 0 0])
for ir = 1:1:lenx
   rotate(O,[0 0 1],tr,[0 0 0]);
   hold on
   plot3(xv(1:ir),yv(1:ir),zv(1:ir),'Color',[1 0 0])
   drawnow limitrate
   %pause(0.0005)
end
   phi2 = lenx*tr;
    hold on 
    plot3(xv(ir),yv(ir),zv(ir),'Marker',"o",'Color',[0 1 0])
    
    phi = phi1 + phi2;
    if phi > 360
        phi = rem(phi,360);
    end

    hold off
    
    %transfer into geocentric frame rotating with Earth
    format long
    DCMGeo = [cosd(phi) sind(phi) 0;-sind(phi) cosd(phi) 0;0 0 1];
    Geoframe = DCMGeo*CEFE;
    Geox = Geoframe(1);
    Geoy = Geoframe(2);
    Rf = norm(LastPosition);
    
    %latitude and logtitude
    projection = sqrt(Geox^2 + Geoy^2);
    hepsi = atand(RZ/projection);
    lat = hepsi;
    tanlon = Geoy/Geox;
    lon = QuadLog(tanlon,Geox);
      
% Data acquired to display
Rel = (Re*rpol)/sqrt((rpol*cosd(lat))^2 + (Re*sind(lat))^2);
Hf= Rf-Rel; %altitude
rpabs = p/(1+e); %absolute perigee
raabs = p/(1-e); %absolute apogee
rp = rpabs-Rel; %perigee from Earth surface
ra = raabs-Rel; %apogee from Earth surface
a= (rpabs+raabs)/2; %semi-major axis
Tf = T/3600; %period
H = cross(r0,v0); %angular momentom 
%fspeed = dcmCEFE*Speedv(end,:)';
%vr = dot(CEFE,fspeed)
DCMTT = transpose(DCMT);
rvxf=DCMTT*LastPosition';
%final anomaly change
     if  e ~= 0
        ev = (cross(v0,H)/mue) - (r0/norm(r0));
        costhetaf = dot(LastPosition,ev)/(norm(LastPosition)*norm(ev));
        thetaf = acosd(costhetaf);
        truanom = coslog(thetaf,rvxf(2)); %quadrant check
     elseif e == 0
         hfx = CordTrans(w+nue,Omega,i);
         rfx = transpose(hfx)*LastPosition';
         fx = transpose(hfx)*r0;
         cosftheta = dot(rfx,fx)/(norm(rfx)*norm(fx));
         ftheta =acosd(cosftheta);
          truanom = coslog(ftheta,rfx(2)) + nue;
          if truanom > 360
              truanom = rem(truanom,360);
          end
     end
          %calculate RAAN (when J2 is acive)
     H = cross(LastPosition,Speedv(end,:));
     I = [1 0 0];
     K= [0 0 1];
     Na = cross(K,H);
     if i ~= 0 %inclination
         cosomeg = dot(I,Na)/norm(Na);
         fOmega = acosd(cosomeg);
         delomeg = coslog(fOmega,Na(2));
         RAAN = delomeg;
     elseif i == 0  %inclination
         RAAN = 0;
     end
     if i==180
         RAAN =0;
     end
     if e ~= 0 && i==180
         RAAN =0;
     end
     
     %calculate Arg of perigee when J2 is active
     if e > 0 && i > 0 && i ~= 90 && i~= 180
         cosw = dot(Na,ev)/(norm(Na)*e);
         wf = acosd(cosw);
         delw = coslog(wf,ev(3));
         Argofp = delw;  
     else
         Argofp = 0;
     end
     %min and max speed
     MinV= min(Speed);
     MaxV= max(Speed);
     
           %2D projection on a map
     %dcmCEFEt=[cosd(-Thetagdeg-phi1) sind(-Thetagdeg-phi1) 0;-sind(-Thetagdeg-phi1) cosd(-Thetagdeg-phi1) 0;0 0 1];
   CheckBox1 = app.GroundtrackCheckBox.Value;
 if CheckBox1 == 1
     r0t = r0;
    v0t = v0;
    y0t = [r0t;v0t];
    tspant = 0:1:Period;
    [tt,yt]= ode113(Odefunc,tspant,y0t,options);
    xvt = yt(:,1);
    yvt = yt(:,2);
    zvt = yt(:,3);
    lenxt = length(tt);   
    rspeed = 360/EarthRot; %deg per sec
    phi3 = Thetagdeg + phi1;
    if phi3 > 360
        phi3 = rem(phi3,360);
    end
        
    for ix = 1:1:lenxt-1       
        proxy(ix) = sqrt(xvt(ix)^2 + yvt(ix)^2);
        latv(ix) = atand(zvt(ix)/proxy(ix));
        rref(:,ix) = [cosd(phi3 + rspeed*(ix)) sind(phi3+rspeed*(ix)) 0;-sind(phi3+rspeed*(ix)) cosd(phi3+rspeed*(ix)) 0;0 0 1]*[xvt(ix);yvt(ix);0];
        tanlonv(ix) = rref(2,ix)/rref(1,ix);
        lonv(ix) = QuadLog(tanlonv(ix),rref(1,ix));  %QuadLog(tanlonv(ix),rref(1,ix));
    end
    %plotting
    fig3 = figure('Name','2D-View Window','NumberTitle','off');
     plot(lonv(1),latv(1),'Marker','o','Color',[1 0 0])
     hold on
     scatter(lonv,latv,'.')
     hold on
     plot(lonv(end),latv(end),'Marker',"o",'LineWidth',2,"Color",[0 1 0])
     xlim([-180 180])
     ylim([-90 90])
     title('Satellite Ground Track','FontSize',18,'Fontweight',"bold")
     xlabel('Lon (deg)','FontSize',12,'Fontweight',"bold")
     ylabel('Lat (deg)','FontSize',12,'Fontweight',"bold")
     pos_fig1 = [0 0 720 360];
     set(fig3,'Position',pos_fig1)
    hold on
         Map2D = imread('earthmap.jpg'); 
    hMap2D = image(xlim,-ylim,Map2D); 
    uistack(hMap2D,'bottom')
    hold off  
 end
     
     % to show results in the message boxes
     app.MaxspeedkmEditField.Value = MaxV;
     app.MinspeedkmEditField.Value = MinV;    
     app.SemimajorAxiskmEditField.Value = a;
     app.PerigeekmEditField.Value = rp;
     app.ApogeekmEditField.Value = ra;
     app.NoofrevolutionsEditField.Value = NofRev;
     app.AltitudekmEditField.Value = Hf;
     app.PeriodhrsEditField.Value = Tf;
     app.XEditField.Value = RX;
     app.YEditField.Value = RY;
     app.ZEditField.Value = RZ;
     app.IEditField.Value = Speedv(end,1);
     app.JEditField.Value = Speedv(end,2);
     app.KEditField.Value = Speedv(end,3);
     app.LATEditField.Value = lat;
     app.LONEditField.Value = lon;
     app.finaltrueanomalydegEditField.Value = truanom;
     app.FinalRAANdegEditField.Value = RAAN;
     app.FinalArgofperigeedegEditField.Value = Argofp;
     
     %speed and altitude vs. time figure
 CheckBox2 = app.SpeedAltitudeCheckBox.Value;
 if CheckBox2 == 1
figure('Name','Speed & altitude','NumberTitle','off')
subplot(2,1,1)
plot(t/3600,Speed)
title('Speed over time','FontSize',12,'FontWeight','bold','Color',[1 0 0])
ylabel('Speed (km/s)','FontSize',12,'FontWeight','bold','Color',[1 0 0])
xlabel('Time (hrs)','FontSize',12,'FontWeight','bold','Color',[1 0 0])
grid on
subplot(2,1,2)
plot(t/3600,Rt)
title('Altitude over time','FontSize',12,'FontWeight','bold','Color',[1 0 0])
ylabel('Altitude (km)','FontSize',12,'FontWeight','bold','Color',[1 0 0])
xlabel('Time (hrs)','FontSize',12,'FontWeight','bold','Color',[1 0 0])
grid on
 end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%End of main program
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


%Twobody + J2 function
function [dydt]= J2ode(t,y)
Rv=y(1:3);
R=norm(Rv);
rdot=y(4:6);
mue=398600.44189;
%J2 perturbation
 Val=app.J2PerturbationSwitch.Value;
if strcmpi(Val,'On')==1
    J2 = 1.08262668e-3;
else 
    J2 = 0;
end 
Re  = 6378.137;
Omegadot= -1.5*J2*(Re^2)*mue/(R^5);
J2x= Omegadot*(Rv(1))*(5*Rv(3)^2/R^2-1); 
J2y= Omegadot*(Rv(2))*(5*Rv(3)^2/R^2-1); 
J2z= Omegadot*(Rv(3))*(5*Rv(3)^2/R^2-3); 
J2v= [J2x;J2y;J2z];

    %rddot = -mue.*Rv/R^3 + J2v;  
    rddot = -mue.*Rv/R^3 - J2v;
    dydt = [rdot;rddot];
    
end
%coordinate transformation
function [dcm]= CordTrans(w,Omega,i)
r1c1=(cosd(w)*cosd(Omega))-(sind(w)*cosd(i)*sind(Omega));
r1c2=(cosd(w)*sind(Omega))+(sind(w)*cosd(i)*cosd(Omega));
r1c3=sind(w)*sind(i);
r2c1=-(sind(w)*cosd(Omega))-(cosd(w)*cosd(i)*sind(Omega));
r2c2=-(sind(w)*sind(Omega))+(cosd(w)*cosd(i)*cosd(Omega));
r2c3=cosd(w)*sind(i);
r3c1=sind(i)*sind(Omega);
r3c2=-sind(i)*cosd(Omega);
r3c3=cosd(i);

DCM= [r1c1 r1c2 r1c3;r2c1 r2c2 r2c3;r3c1 r3c2 r3c3];
dcm=transpose(DCM);
end
%Earth coordinate transformation
function [Theta] = jd2gmst(JD,UT,UTM)
TU = (JD - 2451545)/36525;
format long
GMSTUT = 24110.54841 + (8640184.812866*TU) + (0.093104)*(TU^2) - (6.2e-06)*(TU^3);
Theta = GMSTUT*(360/86400) + UT + (UTM/60);
if Theta > 360
    Theta = rem(Theta,360);
end
end
%lon quadrant logic function
function [lon] = QuadLog(tanlon,Geox)
     if Geox >= 0 && tanlon >= 0 %first quadrant
        lon = atand(tanlon);
    elseif Geox <= 0 && tanlon <= 0 %second quadrant
        lon = 180 + atand(tanlon);
    elseif Geox <= 0 && tanlon >= 0 %third quadrant
        lon = -180 + atand(tanlon);
    elseif Geox >= 0 && tanlon <= 0 %fourth quadrant
        lon = atand(tanlon);
    end
end
%calculating mean anomaly
function [theta] =  MeanAnom(M,E,FE)
while abs(FE)>1e-10
E1= E+(M-E+e*sind(E))/(1- e*cosd(E));
FE = E1-(e*sind(E1))-M;
E = E1;
end
theta = 2*atand(sqrt((1+e)/(1-e))*tand(E/2));
    if theta < 0
        theta = theta+360;
    end
    if e == 0
        theta = M;
    end
end
%cosine logic
function [truanom] = coslog(theta,Vr)
truanom = theta;
    if cosd(theta) >= 0 && Vr >= 0
        truanom = theta;
    elseif cosd(theta) <= 0 && Vr >= 0
        truanom = theta;
    elseif cosd(theta) <= 0 && Vr <= 0
        truanom = 360 - theta;
    elseif cosd(theta) >= 0 && Vr <= 0
        truanom = 360 - theta;
    end
end
        end

        % Value changed function: DurationSpinner
        function DurationSpinnerValueChanged(app, event)
            value = app.DurationSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerateorbitButton = uibutton(app.UIFigure, 'push');
            app.GenerateorbitButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateorbitButtonPushed, true);
            app.GenerateorbitButton.Text = 'Generate orbit';
            app.DurationSpinnerLabel = uilabel(app.UIFigure);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Limits = [0 Inf];
            app.DurationSpinner.ValueDisplayFormat = '%.0f';
            app.DurationSpinner.ValueChangedFcn = createCallbackFcn(app, @DurationSpinnerValueChanged, true);
            app.DurationSpinner.Value = 1;
            app.EccentricitySpinnerLabel = uilabel(app.UIFigure);
            app.EccentricitySpinnerLabel.Text = 'Eccentricity';
            app.EccentricitySpinner = uispinner(app.UIFigure);
            app.EccentricitySpinner.Step = 0.0001;
            app.EccentricitySpinner.Limits = [0 1];
            app.MeanMotionSpinnerLabel = uilabel(app.UIFigure);
            app.MeanMotionSpinnerLabel.Text = {'Mean Motion'; ''};
            app.MeanMotionSpinner = uispinner(app.UIFigure);
            app.MeanMotionSpinner.Step = 0.01;
            app.MeanMotionSpinner.Limits = [0 Inf];
            app.MeanMotionSpinner.Value = 15;
            app.InclinationdegSpinnerLabel = uilabel(app.UIFigure);
            app.InclinationdegSpinnerLabel.Text = {'Inclination (deg)'; ''};
            app.InclinationdegSpinner = uispinner(app.UIFigure);
            app.InclinationdegSpinner.Limits = [0 180];
            app.RAANdegSpinnerLabel = uilabel(app.UIFigure);
            app.RAANdegSpinnerLabel.Text = 'RAAN (deg)';
            app.RAANdegSpinner = uispinner(app.UIFigure);
            app.RAANdegSpinner.Limits = [0 360];
            app.ArgofPerigeedegSpinnerLabel = uilabel(app.UIFigure);
            app.ArgofPerigeedegSpinnerLabel.Text = 'Arg of Perigee (deg)';
            app.ArgofPerigeedegSpinner = uispinner(app.UIFigure);
            app.ArgofPerigeedegSpinner.Limits = [0 360];
            app.MeanAnomalydegSpinnerLabel = uilabel(app.UIFigure);
            app.MeanAnomalydegSpinnerLabel.Text = 'Mean Anomaly (deg)';
            app.MeanAnomalydegSpinner = uispinner(app.UIFigure);
            app.MeanAnomalydegSpinner.Limits = [0 360];
            app.J2PerturbationSwitchLabel = uilabel(app.UIFigure);
            app.J2PerturbationSwitchLabel.Text = 'J2 Perturbation';
            app.J2PerturbationSwitch = uiswitch(app.UIFigure, 'slider');
            app.SemimajorAxiskmEditFieldLabel = uilabel(app.UIFigure);
            app.SemimajorAxiskmEditFieldLabel.Text = 'Semi-major Axis (km)';
            app.SemimajorAxiskmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemimajorAxiskmEditField.RoundFractionalValues = 'on';
            app.SemimajorAxiskmEditField.ValueDisplayFormat = '%.3f';
            app.SemimajorAxiskmEditField.Editable = 'off';
            app.PerigeekmEditFieldLabel = uilabel(app.UIFigure);
            app.PerigeekmEditFieldLabel.Text = 'Perigee (km)';
            app.PerigeekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PerigeekmEditField.RoundFractionalValues = 'on';
            app.PerigeekmEditField.ValueDisplayFormat = '%.3f';
            app.PerigeekmEditField.Editable = 'off';
            app.ApogeekmEditFieldLabel = uilabel(app.UIFigure);
            app.ApogeekmEditFieldLabel.Text = 'Apogee (km)';
            app.ApogeekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApogeekmEditField.RoundFractionalValues = 'on';
            app.ApogeekmEditField.ValueDisplayFormat = '%.3f';
            app.ApogeekmEditField.Editable = 'off';
            app.NoofrevolutionsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofrevolutionsEditFieldLabel.Text = 'No. of revolutions';
            app.NoofrevolutionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofrevolutionsEditField.ValueDisplayFormat = '%.3f';
            app.NoofrevolutionsEditField.Editable = 'off';
            app.AltitudekmEditFieldLabel = uilabel(app.UIFigure);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudekmEditField.ValueDisplayFormat = '%.3f';
            app.AltitudekmEditField.Editable = 'off';
            app.PeriodhrsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodhrsEditFieldLabel.Text = 'Period (hrs)';
            app.PeriodhrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodhrsEditField.ValueDisplayFormat = '%.3f';
            app.PeriodhrsEditField.Editable = 'off';
            app.SpeedAltitudeCheckBox = uicheckbox(app.UIFigure);
            app.SpeedAltitudeCheckBox.Text = 'Speed & Altitude';
            app.MaxspeedkmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxspeedkmEditFieldLabel.Text = 'Max speed (km)';
            app.MaxspeedkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxspeedkmEditField.ValueDisplayFormat = '%.3f';
            app.MaxspeedkmEditField.Editable = 'off';
            app.MinspeedkmEditFieldLabel = uilabel(app.UIFigure);
            app.MinspeedkmEditFieldLabel.Text = 'Min speed (km)';
            app.MinspeedkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinspeedkmEditField.ValueDisplayFormat = '%.3f';
            app.MinspeedkmEditField.Editable = 'off';
            app.LastpositionvectorsLabel = uilabel(app.UIFigure);
            app.LastpositionvectorsLabel.Text = {'Last position vectors'; ''};
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.3f';
            app.ZEditField.Editable = 'off';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueDisplayFormat = '%.3f';
            app.IEditField.Editable = 'off';
            app.JEditFieldLabel = uilabel(app.UIFigure);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.UIFigure, 'numeric');
            app.JEditField.ValueDisplayFormat = '%.3f';
            app.JEditField.Editable = 'off';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.ValueDisplayFormat = '%.3f';
            app.KEditField.Editable = 'off';
            app.kmsLabel = uilabel(app.UIFigure);
            app.kmsLabel.Text = 'km/s';
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.LATEditFieldLabel = uilabel(app.UIFigure);
            app.LATEditFieldLabel.Text = 'LAT';
            app.LATEditField = uieditfield(app.UIFigure, 'numeric');
            app.LATEditField.ValueDisplayFormat = '%.6f';
            app.LATEditField.Editable = 'off';
            app.LONEditFieldLabel = uilabel(app.UIFigure);
            app.LONEditFieldLabel.Text = 'LON';
            app.LONEditField = uieditfield(app.UIFigure, 'numeric');
            app.LONEditField.ValueDisplayFormat = '%.6f';
            app.LONEditField.Editable = 'off';
            app.andSpinnerLabel = uilabel(app.UIFigure);
            app.andSpinnerLabel.Text = 'and';
            app.andSpinner = uispinner(app.UIFigure);
            app.andSpinner.Limits = [0 59];
            app.andSpinner.ValueDisplayFormat = '%.1f';
            app.hoursLabel = uilabel(app.UIFigure);
            app.hoursLabel.Text = '(hours)';
            app.minutesLabel = uilabel(app.UIFigure);
            app.minutesLabel.Text = '(minutes)';
            app.UserdefinedtimeUTCCheckBox = uicheckbox(app.UIFigure);
            app.UserdefinedtimeUTCCheckBox.Text = 'User defined time (UTC)';
            app.HrsSpinnerLabel = uilabel(app.UIFigure);
            app.HrsSpinnerLabel.Text = 'Hrs';
            app.HrsSpinner = uispinner(app.UIFigure);
            app.HrsSpinner.Limits = [0 23];
            app.HrsSpinner.ValueDisplayFormat = '%.0f';
            app.HrsSpinner.Value = 4;
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.Limits = [0 59];
            app.MinSpinner.ValueDisplayFormat = '%.1f';
            app.finaltrueanomalydegEditFieldLabel = uilabel(app.UIFigure);
            app.finaltrueanomalydegEditFieldLabel.Text = {'final true anomaly (deg)'; ''};
            app.finaltrueanomalydegEditField = uieditfield(app.UIFigure, 'numeric');
            app.finaltrueanomalydegEditField.ValueDisplayFormat = '%.5f';
            app.finaltrueanomalydegEditField.Editable = 'off';
            app.WhenJ2isactiveLabel = uilabel(app.UIFigure);
            app.WhenJ2isactiveLabel.Text = 'When J2 is active';
            app.FinalRAANdegEditFieldLabel = uilabel(app.UIFigure);
            app.FinalRAANdegEditFieldLabel.Text = 'Final RAAN (deg) ';
            app.FinalRAANdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalRAANdegEditField.ValueDisplayFormat = '%.5f';
            app.FinalRAANdegEditField.Editable = 'off';
            app.FinalArgofperigeedegEditFieldLabel = uilabel(app.UIFigure);
            app.FinalArgofperigeedegEditFieldLabel.Text = 'Final Arg of perigee (deg)';
            app.FinalArgofperigeedegEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalArgofperigeedegEditField.ValueDisplayFormat = '%.5f';
            app.FinalArgofperigeedegEditField.Editable = 'off';
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.GroundtrackCheckBox = uicheckbox(app.UIFigure);
            app.GroundtrackCheckBox.Text = 'Ground track';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end